classdef MA1_s1760559_Premkumar_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AmplitudeTimeGraphUIFigure     matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        PhaseVocoderScript             matlab.ui.control.Button
        HearInputAudioButton           matlab.ui.control.Button
        StopAudioButton                matlab.ui.control.Button
        HearOutputAudioButton          matlab.ui.control.Button
        BasicTimeStretcherButton       matlab.ui.control.Button
        SelectFunctionLabel            matlab.ui.control.Label
        EnterAudioClipnamewithwavextensionNoquotesEditFieldLabel  matlab.ui.control.Label
        EnterAudioClipnamewithwavextensionNoquotesEditField  matlab.ui.control.EditField
        LoadButton                     matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        Graph1                         matlab.ui.control.UIAxes
        Graph2                         matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        OverlapFactorbetwen0and1Label  matlab.ui.control.Label
        OverlapFactorbetwen0and1EditField  matlab.ui.control.NumericEditField
        Qbetween001and5EditFieldLabel  matlab.ui.control.Label
        Qbetween001and5EditField       matlab.ui.control.NumericEditField
        Framelengthinsecondsbetween0and1Label  matlab.ui.control.Label
        Framelengthinsecondsbetween0and1EditField  matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                        Ruthu Prem Kumar  Nov 2019
%                     Phase Vocoder/Time Stretcher App
%
%
% App that lets you modify time stretch factor,Overlap
% factor and Frame length after loading an audio file, and then plot it.
%
% It also lets you hear the input and otput audio files each time.
% 
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
%     Instructions
%     1. Load audio file from same folder as the app
%     2. Select values for Q, Of and Frame length
%     3. Press either 'Phase Vocoder' or 'Time Stretcher' to plot signals for corresponding parameters
%     4. Click 'Hear Input' or 'Hear Output' to hear the audio signals
%     5. Click 'Load' again to reset the input signal
%     6. Modify the parameters(Q,Of or frame length) and repeat step 3 and 4.
%     


    properties (Access = private)
        %Private variables for all functions
        x;
        y;
        Fs;%
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.AmplitudeTimeGraphUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {589, 589};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {322, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: PhaseVocoderScript
        function PhaseVocoderScriptButtonPushed(app, event)
            
            x = app.x;
            Fs = app.Fs;           
       
           
            %in case of stereo, to mono
            x = 0.5*sum(x,2);
            
            %Inputted Fields
            Q = app.Qbetween001and5EditField.Value;
            Of = app.OverlapFactorbetwen0and1EditField.Value;
            Fr_len = app.Framelengthinsecondsbetween0and1EditField.Value;
            
            %frame length
            N = round(Fs*Fr_len);
            
            %Hop size HA
            HA = round(N*(1-Of));
            
            %Making N a multiple of HA
            N = HA/(1-Of);
            
            %Synthesis Hop Size
            HS = round(Q*HA);
            
            %Hann window
            
            t=[0:N-1];
            win=0.5*(1-cos(2*pi*t/(N-1)));
            win=win.';
            
            %number of samples (total)
            L = length(x);
            
            %Finding NFFT frame length
            
            n=0;
            
            while true
                if power(2,n)>=N
                    NFFT = power(2,n);
                    break;
                else
                    n=n+1;
                end
            end
            
            %Creating zero vectors
            
            phi_m=zeros(NFFT,1);
            phi_m1=zeros(NFFT,1);
            theta_m=zeros(NFFT,1);
            theta_m1=zeros(NFFT,1);
            omega_m=zeros(NFFT,1);
            omega_m1=zeros(NFFT,1);
            
            %Frequency bin
            
            k=[0:NFFT-1];
            omega_k(k+1)=2*pi*k/NFFT;
            
            omega_k=omega_k.';
            
            %Zero padding the start of the signal
            
            x=x.';
            x=[zeros(1,N) x];
            
            %Zero padding the end of the signal
            
            %Finding the number of additional samples required to zero pad the end
            
            
            NF=floor((N+L)/HA);
            
            tot_samples = (NF-1)*HA + N;          %tot_samples = Total number of samples in final audio signal
            
            rem = tot_samples-(L+N);              %number of additional samples required to zero pad
            
            x=[x zeros(1,rem)];
            
            x=x.';
            
            %Output vector y
            
            y=zeros(N+((NF-1)*HS),1);
            
            %Adding analysis frames to y
            
            for m=0:NF-1
                
                nx=(HA*m)+1;
                ny=(HS*m)+1;
               
                
                %DFT of frame
                
                X=fft(x(nx:nx+N-1).*win,NFFT);
                Xmag = abs(X); Xang = angle(X);
                phi_m1 = Xang;
                
                
                omega_m1 = omega_k + ppa(phi_m1-phi_m-omega_k*HA)/HA;
                theta_m1 = theta_m + HS*omega_m1;
                Y = Xmag.*exp(1i*theta_m1); 
                
            
                Y_inv = ifft(Y);
                
                Y_inv = Y_inv(1:N);
                Y_inv = real(Y_inv);
                y(ny:ny+N-1) = y(ny:ny+N-1) + Y_inv.*win;
                
                phi_m = phi_m1;
                theta_m = theta_m1;
                omega_m = omega_m1;
                
            
            end
            
            %Normalizing y
            
            y = (y/max(abs(y)))*max(abs(x));

            t1=[1:1/Fs:(length(x)/Fs)+1-(1/Fs)];
            
            % Plot the audio signals
            plot(app.Graph1,t1,x);
            xlabel(app.Graph1,'Time(s)');
            ylabel(app.Graph1,'Amplitude');
            title(app.Graph1,'Input Signal');
            
            t2=[1:1/Fs:(length(y)/Fs)+1-(1/Fs)];
            
            plot(app.Graph2,t2,y);
            xlabel(app.Graph2,'Time(s)');
            ylabel(app.Graph2,'Amplitude');
            title(app.Graph2,'Output Signal');
            
            app.x = x;
            app.y = y;
            app.Fs = Fs;
      
        end

        % Value changed function: Qbetween001and5EditField
        function Qbetween001and5EditFieldValueChanged(app, event)
            value = app.Qbetween001and5EditField.Value;
            
        end

        % Button pushed function: HearInputAudioButton
        function HearInputAudioButtonPushed(app, event)
            signal = app.x;
            Framerate = app.Fs;
            soundsc(signal,Framerate);
            
        end

        % Button pushed function: StopAudioButton
        function StopAudioButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: HearOutputAudioButton
        function HearOutputAudioButtonPushed(app, event)
            signal = app.y;
            Framerate = app.Fs;
            soundsc(signal,Framerate);
        end

        % Button pushed function: BasicTimeStretcherButton
        function BasicTimeStretcherButtonPushed(app, event)


            x = app.x;
            Fs = app.Fs;
            %in case of stereo, to mono
            x = 0.5*sum(x,2);
            
            %Inputted values
            Q = app.Qbetween001and5EditField.Value;
            Of = app.OverlapFactorbetwen0and1EditField.Value;
            Fr_len = app.Framelengthinsecondsbetween0and1EditField.Value;
            
            %frame length           
            N = round(Fs*Fr_len);
            
            %Hop size HA
            HA = round(N*(1-Of));
            
            N = HA/(1-Of);
            
            %Synthesis Hop Size
            HS = round(Q*HA);
            
            %Hann window
            
            t=[0:N-1];
            
            win=0.5*(1-cos(2*pi*t/N));
            win=win.';
            
            %number of samples (total)
            L = length(x);
            
            %Zero padding the start of the signal
            
            x=x.';
            x=[zeros(1,N) x];
            
            %Zero padding the end of the signal
            
            %Finding the number of additional samples required to zero pad the end
            
            n=0;
            
            while n>-1
                if (N +(n*HA))>(L+N)
                    
                    break
                    
                else
                    n=n+1;
                end
            end
            
            
            NF=floor((N+L)/HA);
            
            
            tot_samples = (NF-1)*HA + N;          %tot_samples = Total number of samples in final audio signal
            
            remainder = tot_samples-(L+N);              %number of additional samples required to zero pad
            
            x=[x zeros(1,remainder)];
            
            x=x.';
            
            %Output vector y
            
            y=zeros(N+((NF-1)*HS),1);
            
            %Adding analysis frames to y
            
            for m=0:NF-1
                
                nx=(HA*m)+1;
                ny=(HS*m)+1;
                y(ny:ny+(N-1)) = y(ny:ny+(N-1)) + (x(nx:nx+(N-1)).*win).*win;
                
            
            end
            
            %Normalizing y
            
            y = (y/max(abs(y)))*max(abs(x));
                           
            t1=[1:1/Fs:(length(x)/Fs)+1-(1/Fs)];
            
            % Plot the audio signals
            plot(app.Graph1,t1,x);
            xlabel(app.Graph1,'Time(s)');
            ylabel(app.Graph1,'Amplitude');
            title(app.Graph1,'Input Signal');
            
            t2=[1:1/Fs:(length(y)/Fs)+1-(1/Fs)];
            
            plot(app.Graph2,t2,y);
            xlabel(app.Graph2,'Time(s)');
            ylabel(app.Graph2,'Amplitude');
            title(app.Graph2,'Output Signal');
            
            app.x = x;
            app.y = y;
            app.Fs = Fs;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            clear app.x; clear app.y; clear app.Fs;
            [x,Fs]=audioread(char(app.EnterAudioClipnamewithwavextensionNoquotesEditField.Value));
            app.x = x;
            app.Fs = Fs;
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AmplitudeTimeGraphUIFigure = uifigure('Visible', 'off');
            app.AmplitudeTimeGraphUIFigure.Name = 'Amplitude TIme Graph';
            app.AmplitudeTimeGraphUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.AmplitudeTimeGraphUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PhaseVocoderScript = uibutton(app.LeftPanel, 'push');
            app.PhaseVocoderScript.ButtonPushedFcn = createCallbackFcn(app, @PhaseVocoderScriptButtonPushed, true);
            app.PhaseVocoderScript.Text = {'Phase Vocoder'; ''};
            app.HearInputAudioButton = uibutton(app.LeftPanel, 'push');
            app.HearInputAudioButton.ButtonPushedFcn = createCallbackFcn(app, @HearInputAudioButtonPushed, true);
            app.HearInputAudioButton.Text = 'Hear Input Audio';
            app.StopAudioButton = uibutton(app.LeftPanel, 'push');
            app.StopAudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopAudioButtonPushed, true);
            app.StopAudioButton.Text = 'Stop Audio';
            app.HearOutputAudioButton = uibutton(app.LeftPanel, 'push');
            app.HearOutputAudioButton.ButtonPushedFcn = createCallbackFcn(app, @HearOutputAudioButtonPushed, true);
            app.HearOutputAudioButton.Text = 'Hear Output Audio';
            app.BasicTimeStretcherButton = uibutton(app.LeftPanel, 'push');
            app.BasicTimeStretcherButton.ButtonPushedFcn = createCallbackFcn(app, @BasicTimeStretcherButtonPushed, true);
            app.BasicTimeStretcherButton.Text = 'Basic Time Stretcher';
            app.SelectFunctionLabel = uilabel(app.LeftPanel);
            app.SelectFunctionLabel.Text = 'Select Function';
            app.EnterAudioClipnamewithwavextensionNoquotesEditFieldLabel = uilabel(app.LeftPanel);
            app.EnterAudioClipnamewithwavextensionNoquotesEditFieldLabel.Text = {'Enter Audio Clip name'; '(with.wav extension)'; '(No quotes)'};
            app.EnterAudioClipnamewithwavextensionNoquotesEditField = uieditfield(app.LeftPanel, 'text');
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load ';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = {'WARNING : DO NOT PRESS ANY OF THE '; 'BUTTONS BELOW WITHOUT LOADING A VALID AUDIO FILE FIRST'};
            app.RightPanel = uipanel(app.GridLayout);
            app.Graph1 = uiaxes(app.RightPanel);
            title(app.Graph1, '')
            xlabel(app.Graph1, '')
            ylabel(app.Graph1, '')
            app.Graph2 = uiaxes(app.RightPanel);
            title(app.Graph2, '')
            xlabel(app.Graph2, '')
            ylabel(app.Graph2, '')
            app.Panel = uipanel(app.RightPanel);
            app.Panel.Title = 'Parameters';
            app.OverlapFactorbetwen0and1Label = uilabel(app.Panel);
            app.OverlapFactorbetwen0and1Label.Text = 'Overlap Factor(betwen 0 and 1)';
            app.OverlapFactorbetwen0and1EditField = uieditfield(app.Panel, 'numeric');
            app.OverlapFactorbetwen0and1EditField.Limits = [0 1];
            app.OverlapFactorbetwen0and1EditField.Value = 0.75;
            app.Qbetween001and5EditFieldLabel = uilabel(app.Panel);
            app.Qbetween001and5EditFieldLabel.Text = 'Q (between 0.01 and 5)';
            app.Qbetween001and5EditField = uieditfield(app.Panel, 'numeric');
            app.Qbetween001and5EditField.Limits = [0.01 5];
            app.Qbetween001and5EditField.ValueChangedFcn = createCallbackFcn(app, @Qbetween001and5EditFieldValueChanged, true);
            app.Qbetween001and5EditField.Value = 1;
            app.Framelengthinsecondsbetween0and1Label = uilabel(app.Panel);
            app.Framelengthinsecondsbetween0and1Label.Text = 'Frame length(in seconds, between 0 and 1)';
            app.Framelengthinsecondsbetween0and1EditField = uieditfield(app.Panel, 'numeric');
            app.Framelengthinsecondsbetween0and1EditField.Limits = [0 1];
            app.Framelengthinsecondsbetween0and1EditField.Value = 0.02;
            % Show the figure after all components are created
            app.AmplitudeTimeGraphUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MA1_s1760559_Premkumar_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AmplitudeTimeGraphUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AmplitudeTimeGraphUIFigure)
        end
    end
end